classdef extractor_App_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        uipanel1                      matlab.ui.container.Panel
        extractFeedbackString         matlab.ui.control.Label
        redSomaticExtractCheck        matlab.ui.control.CheckBox
        somaExtractCheck              matlab.ui.control.CheckBox
        dendriteExtractCheck          matlab.ui.control.CheckBox
        axonExtractCheck              matlab.ui.control.CheckBox
        boutonExtractCheck            matlab.ui.control.CheckBox
        vascularExtractCheck          matlab.ui.control.CheckBox
        neuropilExtractCheck          matlab.ui.control.CheckBox
        extractButton                 matlab.ui.control.Button
        medianExtractToggle           matlab.ui.control.CheckBox
        roiDisplaySlider              matlab.ui.control.Slider
        displayedROICounter           matlab.ui.control.EditField
        text1                         matlab.ui.control.Label
        uipanel7                      matlab.ui.container.Panel
        corAxis                       matlab.ui.control.UIAxes
        featureHist                   matlab.ui.control.UIAxes
        featurePlot                   matlab.ui.control.UIAxes
        showCorrelatedToggle          matlab.ui.control.CheckBox
        corThresholdEntry             matlab.ui.control.EditField
        text9                         matlab.ui.control.Label
        relatedCellsReturn            matlab.ui.control.EditField
        text18                        matlab.ui.control.Label
        groupMarkerBtn                matlab.ui.control.Button
        groupCounter                  matlab.ui.control.EditField
        text19                        matlab.ui.control.Label
        relatedValuesReturn           matlab.ui.control.EditField
        relatedValuesSt               matlab.ui.control.Label
        rcBtn_generic                 matlab.ui.control.Button
        relatedDistReturn             matlab.ui.control.EditField
        text22                        matlab.ui.control.Label
        keepFirstBtn                  matlab.ui.control.Button
        killSelectedBtn               matlab.ui.control.Button
        relatedPixelCountReturn       matlab.ui.control.EditField
        text24                        matlab.ui.control.Label
        sortByMenu                    matlab.ui.control.DropDown
        sortAscend                    matlab.ui.control.CheckBox
        killGroupBtn                  matlab.ui.control.Button
        killFirstBtn                  matlab.ui.control.Button
        text27                        matlab.ui.control.Label
        pushbutton38                  matlab.ui.control.Button
        flagROIButton                 matlab.ui.control.Button
        deleteFlagged                 matlab.ui.control.Button
        saveTracesPDFBtn              matlab.ui.control.Button
        uipanel8                      matlab.ui.container.Panel
        text11                        matlab.ui.control.Label
        text14                        matlab.ui.control.Label
        text15                        matlab.ui.control.Label
        text16                        matlab.ui.control.Label
        xLowTrace                     matlab.ui.control.EditField
        xHighTrace                    matlab.ui.control.EditField
        yLowTrace                     matlab.ui.control.EditField
        yHighTrace                    matlab.ui.control.EditField
        uipanel9                      matlab.ui.container.Panel
        smoothWindowEntry             matlab.ui.control.EditField
        movingAvgBtn                  matlab.ui.control.Button
        slidingBaselineBtn            matlab.ui.control.Button
        movBaselineWinEntry           matlab.ui.control.EditField
        useQuantForDFToggle           matlab.ui.control.CheckBox
        typeSelectorMenu              matlab.ui.control.DropDown
        neuropilCorrectBtn            matlab.ui.control.Button
        neuropilScaleEntry            matlab.ui.control.EditField
        feedbackString                matlab.ui.control.Label
        oopsiBtn                      matlab.ui.control.Button
        uipanel5                      matlab.ui.container.Panel
        somaticROIs_DisplayToggle     matlab.ui.control.CheckBox
        dendriticROIs_DisplayToggle   matlab.ui.control.CheckBox
        axonalROIs_DisplayToggle      matlab.ui.control.CheckBox
        boutonROIs_DisplayToggle      matlab.ui.control.CheckBox
        vesselROIs_DisplayToggle      matlab.ui.control.CheckBox
        redSomaticROIs_DisplayToggle  matlab.ui.control.CheckBox
        neuropilROIs_DisplayToggle    matlab.ui.control.CheckBox
        plotAsDFToggle                matlab.ui.control.CheckBox
        cleanTraceToggle              matlab.ui.control.CheckBox
        uipanel13                     matlab.ui.container.Panel
        roiMaskAxis                   matlab.ui.control.UIAxes
        uipanel15                     matlab.ui.container.Panel
        workspaceVarBox               matlab.ui.control.ListBox
        refreshWSVarsBtn              matlab.ui.control.Button
        saveWSBtn                     matlab.ui.control.Button
        deleteSelection               matlab.ui.control.Button
        setImageTime                  matlab.ui.control.Button
        uipanel16                     matlab.ui.container.Panel
        roiSelector                   matlab.ui.control.ListBox
        holdCurrentROILinesToggle     matlab.ui.control.CheckBox
        uipanel10                     matlab.ui.container.Panel
        importerBtn                   matlab.ui.control.Button
        roiMakerBtn                   matlab.ui.control.Button
        uipanel6                      matlab.ui.container.Panel
        firstImageEntry               matlab.ui.control.EditField
        endImageEntry                 matlab.ui.control.EditField
        diskRegFlag                   matlab.ui.control.CheckBox
        diskExtractButton             matlab.ui.control.Button
        text4                         matlab.ui.control.Label
        diskExtractSkipByToggle       matlab.ui.control.CheckBox
        diskExtractSkipByEntry        matlab.ui.control.EditField
        binByToggle                   matlab.ui.control.CheckBox
        binByEntry                    matlab.ui.control.EditField
        text36                        matlab.ui.control.Label
        uipanel20                     matlab.ui.container.Panel
        traceDisplay                  matlab.ui.control.UIAxes
        csParserPanel                 matlab.ui.container.Panel
        loadCSHDF                     matlab.ui.control.Button
        csListbox                     matlab.ui.control.ListBox
        refreshCSVarBtn               matlab.ui.control.Button
        plotCSVar                     matlab.ui.control.Button
        setCSTime                     matlab.ui.control.Button
        plotNormToggle                matlab.ui.control.CheckBox
        upSampleTrimImages            matlab.ui.control.Button
        plotScaleEntry                matlab.ui.control.EditField
        text37                        matlab.ui.control.Label
    end

    
    methods (Access = private)
        function numSel = LineSelected(app, hObject, eventdata, handels)
                set(hObject, 'LineWidth', 1.5);
                set(handels(handels ~= hObject), 'LineWidth', 0.4);
                sI=(handels==hObject);
                numSel=find(sI==1);
            
                assignin('base','selectedTraceNum',numSel)
        end
        
        function diskExtractSkipByStartEntry_CreateFcn(app, hObject, eventdata, handles)
                if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                    set(hObject,'BackgroundColor','white');
                end
        end
        
        function genericDispalyTypeToggle(app, hObject, handles)
            
                [allTypes,allColors]=returnAllTypes(app,hObject,handles);
            
                for n=1:numel(allTypes)
                    whoIsOn(n)=eval(['get(app.' allTypes{n} 'ROIs_DisplayToggle, ''Value'');']);
                end
            
                whoIsOn=find(whoIsOn==1);
            
                if numel(whoIsOn)==0
                    set(app.roiSelector,'String','');
                else
                end
            
                roiCounter=0;
                for rI=1:numel(whoIsOn)
                    typeString=allTypes{whoIsOn(rI)};
                    gf=checkForWSVar([typeString 'F']);
                    gdf=checkForWSVar([typeString 'F_DF']);
                    if gf==1 | gdf==1
                        h=evalin('base',[typeString 'RoiCounter']);
                        % Populate the box:
                        for n=1:h
                            roiCounter=roiCounter+1;
                            roisList{roiCounter}=[typeString '_' num2str(n)];
                        end
                        set(app.roiSelector, 'String', '');
                        set(app.roiSelector,'String',roisList);
                        set(app.roiSelector,'Value',h)
                    else
                    end
                end
            
                % now we need to reset the sliders
                tCnt=roiCounter;
                if tCnt>1
                    tnum=str2double(get(app.displayedROICounter,'String'));
                    sliderMin = 1;
                    sliderMax = tCnt(1); % this is variable
                    sliderStep = [1, 1] / (sliderMax - sliderMin); % major and minor steps of 1
                    set(app.roiDisplaySlider, 'Min', sliderMin);
                    set(app.roiDisplaySlider, 'Max', sliderMax);
                    set(app.roiDisplaySlider, 'SliderStep', sliderStep);
                    if tnum<=sliderMax
                        set(app.roiDisplaySlider, 'Value', tnum); % set to beginning of sequence
                        set(app.displayedROICounter,'String', num2str(tnum));
                    else
                        set(app.roiDisplaySlider, 'Value', sliderMin); % set to beginning of sequence
                        tnum=1;
                        set(app.displayedROICounter,'String', '1');
                    end
                    roiDisplaySlider_Callback(hObject, eventdata, handles)
                    guidata(hObject, handles);
            
                elseif tCnt==1
                    tnum=1;
                    sliderMin = 0;
                    sliderMax = 1; % this is variable
                    sliderStep = [1 1];
                    set(app.roiDisplaySlider, 'Min', sliderMin);
                    set(app.roiDisplaySlider, 'Max', sliderMax);
                    set(app.roiDisplaySlider, 'SliderStep', sliderStep);
                    if tnum<=sliderMax
                        set(app.roiDisplaySlider, 'Value', tnum); % set to beginning of sequence
                        set(app.displayedROICounter,'String', num2str(tnum));
                    else
                        set(app.roiDisplaySlider, 'Value', sliderMin); % set to beginning of sequence
                        tnum=1;
                        set(app.displayedROICounter,'String', '1');
                    end
                    roiDisplaySlider_Callback(hObject, eventdata, handles)
                    guidata(hObject, handles);
                else
                end
            
                guidata(hObject, handles);
        end
        
        function typeList = getKnownROITypes(app, hObject, eventdata, handles)
                typeList={'neuropilRois','vascularRois','boutonRois',...
                'axonRois','dendriteRois','somaRois','redSomaticRois'};
                typeList={'neuropil','vessel','bouton',...
                'axonal','dendritic','somatic','redSomatic'};
        end
        
        function plotROI(app, hObject, eventdata, handles)
            
                % main roi load
                [allTypes,allColors,allWidths]=returnAllTypes(hObject,eventdata,handles);
                mainROIBoxID=get(app.roiSelector,'Value');
                allROIStrings=get(app.roiSelector,'String');
                totalROIs=numel(allROIStrings);
                mainROIString=allROIStrings{mainROIBoxID};
                mainROIStringSplit=strsplit(mainROIString,'_');
                mainType=mainROIStringSplit{1};
                mainTypeID=fix(str2double(mainROIStringSplit{2}));
            
                % plot main trace
                pltDF=get(app.plotAsDFToggle,'Value');
                pltClean = get(app.cleanTraceToggle,'Value');
                if pltDF
                    if pltClean
                        mainTrace=evalin('base',[mainType 'F_DF_Clean(' num2str(mainTypeID) ',:);']);
                    else
                        mainTrace=evalin('base',[mainType 'F_DF(' num2str(mainTypeID) ',:);']);
                    end
                else
                    mainTrace=evalin('base',[mainType 'F(' num2str(mainTypeID) ',:);']);
                end
            
                try
                    timeVec=evalin('base','imTime');
                catch
                    timeVec=1:numel(mainTrace);
                end
            
                yLow=str2num(get(app.yLowTrace,'String'));
                yHigh=str2num(get(app.yHighTrace,'String'));
            
                axes(app.traceDisplay)
            
                holdLast=get(app.holdCurrentROILinesToggle,'Value');
                if holdLast
                    lstAx=gca;
                    lastLines=lstAx.Children;
                    for n=1:numel(lastLines)
                        lastLine(n).Color=[1,0,0];
                    end
                    hold all
                else
                    hold off
                end
            
                mainColor=[0,0,0];
                plot(timeVec(1:numel(mainTrace(1,:)')),mainTrace(1,:)','Color',mainColor,'LineWidth',1.1);
                lstAx=gca;
                h=lstAx.Children;
                if numel(h)>1
                    for k=2:numel(h)
                        h(k).Color=[1,0,0];
                    end
                else
                end
                hold off
            
                ylim([yLow yHigh])
            
                % plot the mask too
            
                axes(app.roiMaskAxis)
            
                mainMask=evalin('base',[mainType 'ROIs{' num2str(mainTypeID) '}']); %*relatedCorrelations(n);
                g=[1,1,1]; % the mainROI color is white
                rgb=cat(3,mainMask*g(1),mainMask*g(2),mainMask*g(3));
            
            
                if holdLast
                    lAx=gca;
                    lastMask=lAx.Children.CData;
                    g=[1,0,0]; % the current color is white, hold is red
                    % last mask is already rgb
                    rgb=(lastMask+rgb);
                end
            
            
                mP=imshow(rgb);
                a=gca;
                a.YTick=[];
                a.XTick=[];
            
                assignin('base','curHMask',mP);
            
                % plot related traces
                plotRelated=get(app.showCorrelatedToggle,'Value');
                relatedThreshold=str2double(get(app.corThresholdEntry,'String'));
                if plotRelated
                    % get all traces and masks of the types selected
                    allTypes=returnAllTypes(hObject,eventdata,handles);
            
                    for n=1:numel(allTypes)
                        whoIsOn(n)=eval(['get(app.' allTypes{n} 'ROIs_DisplayToggle, ''Value'');']);
                    end
            
                    whoIsOn=find(whoIsOn==1);
                    tracesToGrab=eval(['allTypes([' num2str(whoIsOn) ']);']);
                    allTraces=[];
                    allROIMasks=[];
                    allROICentroids=[];
                    for k=1:numel(tracesToGrab)
                        pltDF=get(app.plotAsDFToggle,'Value');
                        if pltDF
                            if pltClean
                                allTraces=vertcat(allTraces,evalin('base',[tracesToGrab{k} 'F_DF_Clean;']));
                            else
                                allTraces=vertcat(allTraces,evalin('base',[tracesToGrab{k} 'F_DF;']));
                            end
            
                        else
                            allTraces=vertcat(allTraces,evalin('base',[tracesToGrab{k} 'F;']));
                        end
                            allROIMasks=horzcat(allROIMasks,evalin('base',[tracesToGrab{k} 'ROIs;']));
                            allROICentroids=horzcat(allROICentroids,evalin('base',[tracesToGrab{k} 'ROICenters;']));
                    end
            
                    % compute the correlations between the primary and the rest
                    curCorr=corr(repmat(mainTrace,size(allTraces,1),1)',allTraces');
            
                    axes(app.corAxis)
                    plot(curCorr','k-','linewidth',0.5)
                    hold all
                    plot([1 totalROIs],[relatedThreshold relatedThreshold],'r:','LineWidth',1)
                    hold off
                    ylim([-0.1,1.1])
            
                    [csV,csI]=sort(curCorr','descend');
                    csV=csV(:,1);
                    csI=csI(:,1);
            
                    % threshold the correlations and plot the values
                    relatedROIs=csI(csV>=relatedThreshold);
                    relatedCorrelations=csV(csV>=relatedThreshold);
            
                    hold all
                    scatter(relatedROIs,relatedCorrelations,'filled','MarkerEdgeColor',[0 0 0],...
                        'MarkerFaceColor',[0.0,0.5,1.0],'SizeData',70)
                    hold off
            
            
                    % get primary and secondary masks based on their previous ids
                    primaryMask=allROIMasks{mainROIBoxID};
                    primaryCentroid=allROICentroids{mainROIBoxID};
            
                    % related pairwise distance (Euclidean) b is the primary reference
                    for n=1:numel(relatedROIs)
                        relatedPixelCounts(:,n)=numel(find(allROIMasks{n}==1));
                        b=primaryCentroid.Centroid;
                        a=allROICentroids{n}.Centroid;
                        relatedDistances(:,n)=sqrt((b(1)-a(1))^2+((b(2)-a(2))^2));
                    end
            
                    % check for menu sort
                    sL=get(app.sortByMenu,'String');
                    sV=get(app.sortByMenu,'Value');
            
                    sortString=sL{sV};
                    sortUp=get(app.sortAscend,'Value');
                    if sortUp
                        eval(['[tSrtV tSrtI]=sort(related' sortString ',''ascend'');'])
                    else
                        eval(['[tSrtV tSrtI]=sort(related' sortString ',''descend'');'])
                    end
                    % allROIStrings
            
                    relatedROIs=relatedROIs(tSrtI);
                    relatedCorrelations=relatedCorrelations(tSrtI);
                    relatedDistances=relatedDistances(tSrtI);
                    relatedPixelCounts=relatedPixelCounts(tSrtI);
            
            
                    % shrink the total mask and traces to the related ones (including
                    % primary)
                    % keep the traces and masks of interest
                    % the primary is in all, but this is convinent.
                    primarySubgroupID=find(relatedROIs==mainROIBoxID);
                    nonPrimarySubgroupID=setdiff(1:numel(relatedROIs),primarySubgroupID);
            
                    allTraces=allTraces(relatedROIs,:);
                    allROIMasks=allROIMasks(relatedROIs);
                    allROICentroids=allROICentroids(relatedROIs);
            
                    % now get the roi types for all realted
                    allRelatedTypeStrings=allROIStrings(relatedROIs);
                    for n=1:numel(allRelatedTypeStrings)
                        tSplt=strsplit(allRelatedTypeStrings{n},'_');
                        tAcum(n)=tSplt(1);
                    end
            
                    allRelatedTypeStrings=tAcum;
                    primaryTypeString=allRelatedTypeStrings(primarySubgroupID);
                    nonPrimaryTypeString=allRelatedTypeStrings(nonPrimarySubgroupID);
            
                    % update the masks
                    axes(app.roiMaskAxis)
                    lAx=gca;
                    lastMask=lAx.Children.CData;
                    % last mask is already rgb
                    rVals=fix(tSrtV*10);
                    vRange=max(rVals)-min(rVals);
                    mapValNum=numel(rVals)*1;
            
            
                    aa=colormap(jet(mapValNum));
                    rgb=zeros(size(lastMask));
                    for n=1:numel(nonPrimarySubgroupID)
                        mainMask=allROIMasks{nonPrimarySubgroupID(n)};
                        g=aa(n,:);
                        trgb=cat(3,mainMask*g(1),mainMask*g(2),mainMask*g(3));
                        rgb=(rgb+trgb);
                    end
                    rgb=lastMask+rgb;
                    mP=imshow(rgb);
                    mCA=gca;
                    mCA.YTick=[];
                    mCA.XTick=[];
            
                    assignin('base','curHMask',mP);
            
                    % plot the related traces exclude the primary as we've plotted it.
                    axes(app.traceDisplay)
                    % add to accumulating h's for making figures etc.
                    lastH=numel(h);
            
                    for n=1:numel(nonPrimarySubgroupID)
                        % allColors{strcmp(allTypes,nonPrimaryTypeString{n})},
                        g=aa(n,:);
                        hold all
                        h(n+lastH)=plot(timeVec,allTraces(nonPrimarySubgroupID(n),:)','Color',g,...
                            'LineWidth',allWidths(strcmp(allTypes,nonPrimaryTypeString{n})));
                    end
                    hold off
                    assignin('base','curh',h);
                    set(h,'ButtonDownFcn',{@LineSelected,h})
            
            
                    relCString=strjoin(allROIStrings(relatedROIs),',');
                    set(app.relatedCellsReturn,'String',relCString);
            
                    relVString=strjoin(arrayfun(@(x) num2str(x),relatedCorrelations,'UniformOutput',false),',');
                    set(app.relatedValuesReturn,'String',relVString)
                    % assignin('base','dbugV',relatedCorrelations') %todo: 3dplot
            
                    relDString=strjoin(arrayfun(@(x) num2str(x),relatedDistances,'UniformOutput',false),',');
                    set(app.relatedDistReturn,'String',relDString);
                    % assignin('base','dbugD',relatedDistances)
            
                    relPCString=strjoin(arrayfun(@(x) num2str(x),relatedPixelCounts,'UniformOutput',false),',');
                    set(app.relatedPixelCountReturn,'String',relPCString);
                    % assignin('base','dbugPC',relatedPixelCounts)
            
            
                    % relatedDistances=[1]; % debug
                    if numel(relatedDistances)>=2
                        axes(app.featureHist)
                        nhist(relatedDistances,'box');
                        xlim([0 200])
            
                        axes(app.featurePlot)
            
            
                        for n=1:numel(relatedROIs)
                            g=aa(n,:);
                            plot(relatedROIs(n),relatedDistances(n),'o','Color',g)
                            hold all
                        end
            
                        plot([1 size(curCorr,1)],[20 20],'r:','linewidth',1)
                        hold off
                        ylim([0 200])
            
                    else
                        axes(app.featureHist)
                        plot([],[])
                        ylim([0 200])
                        axes(app.featurePlot)
                        plot([],[])
                        hold on
                        plot([1 size(curCorr,1)],[20 20],'r:','linewidth',1)
                        hold off
                        ylim([0 200])
                    end
                else
                    axes(app.corAxis)
                    plot([1 totalROIs],[relatedThreshold relatedThreshold],'r:','LineWidth',1)
                    ylim([-0.1,1.1])
            
                end
            
            
                clear  mainTrace allTraces allROIMasks allROICentroids
                guidata(hObject, handles);
        end
        
        function quantDFEntry_CreateFcn(app, hObject, eventdata, handles)
                if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                    set(hObject,'BackgroundColor','white');
                end
        end
        
        function rcBtn_axon_Callback(app, hObject, eventdata, handles)
            
                refreshWSVarsBtn_Callback(hObject, eventdata, handles)
                guidata(hObject, handles);
        end
        
        function rcBtn_dend_Callback(app, hObject, eventdata, handles)
                refreshWSVarsBtn_Callback(hObject, eventdata, handles)
                guidata(hObject, handles);
        end
        
        function [returnTypeStrings, typeAllColor, lWidths] = returnAllTypes(app, hObject, handles)
                % set all known ROI types here
                returnTypeStrings={'somatic','redSomatic','dendritic','axonal','bouton','vessel','neuropil'};
                typeAllColor={[0,0.8,0.3],[1,0,0],[0,0,1],[1,0,1],[1,1,0],[0.7,0.3,0],[1 0.1 0]};
                lWidths=[1,1,1,1,1,1,1];
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function extractor_OpeningFcn(app)
%                 app.output = hObject;
%                 vars = evalin('base','who');
%                 set(app.workspaceVarBox,'String',vars)
%                 refreshCSVarBtn_Callback(hObject, eventdata, handles)
%             
%                 try
%                     totalImages=evalin('base','numel(metaData.filteredFiles);');
%                     set(app.endImageEntry,'String',num2str(totalImages));
%                 catch
%                 end
%             
%                 try
%                     totalImages=evalin('base','metaData.hdfSize;');
%                     set(app.endImageEntry,'String',num2str(totalImages(3)));
%                 catch
%                 end
%             
%                 if strcmp(computer,'MACI64') || strcmp(computer,'GLNXA64')
%                     macHeaderSize=12;
%                     macFontSize=11;
%                     macUIDecSize=10;
%             
%                 elseif strcmp(computer,'PCWIN64')
%                     macHeaderSize=8;
%                     macFontSize=7;
%                     macUIDecSize=6;
%                 else
%                 end
%             
%                 uiElements={'saveWSBtn','refreshWSVarsBtn','deleteSelection','workspaceVarBox',...
%                 'firstImageEntry','endImageEntry','diskExtractButton','diskRegFlag','checkbox15',...
%                 'dataAppendToggle','diskExtractSkipByToggle','diskExtractSkipByEntry',...
%                 'diskExtractSkipByStartEntry','somaExtractCheck','dendriteExtractCheck',...
%                 'axonExtractCheck','boutonExtractCheck','medianExtractToggle','extractButton',...
%                 'vascularExtractCheck','neuropilExtractCheck','redSomaticExtractCheck',...
%                 'somaticROIs_DisplayToggle','dendriticROIs_DisplayToggle','axonalROIs_DisplayToggle',...
%                 'boutonROIs_DisplayToggle','redSomaticROIs_DisplayToggle','neuropilROIs_DisplayToggle',...
%                 'vesselROIs_DisplayToggle','plotAsDFToggle','movingAvgBtn','smoothWindowEntry',...
%                 'typeSelectorMenu','slidingBaselineBtn','useQuantForDFToggle','movBaselineWinEntry',...
%                 'yHighTrace','yLowTrace','xLowTrace','xHighTrace','saveTracesPDFBtn','holdCurrentROILinesToggle',...
%                 'roiSelector','displayedROICounter','showCorrelatedToggle','corThresholdEntry','sortByMenu',...
%                 'sortAscend','relatedCellsReturn','relatedValuesReturn','relatedDistReturn',...
%                 'relatedPixelCountReturn','groupCounter','killGroupBtn','killFirstBtn','keepFirstBtn',...
%                 'groupMarkerBtn','killSelectedBtn','rcBtn_generic','deleteFlagged','flagROIButton','importerBtn',...
%                 'roiMakerBtn','loadCSHDF','csListbox','plotCSVar','setImageTime','setCSTime','refreshCSVarBtn',...
%                 'plotNormToggle','upSampleTrimImages','plotScaleEntry','binByToggle','binByEntry','neuropilCorrectBtn',...
%                 'neuropilScaleEntry'};
%             
%                 for n=1:numel(uiElements)
%                     eval(['app.' uiElements{n} '.FontSize=macFontSize;'])
%                 end
%             
%                 decUIElements={'text29','text4','text14','text11','text15','text16',...
%                 'corAxis','featureHist','featurePlot','text1','text9','text24','relatedValuesSt',...
%                 'text18','text22','text27','text19','text37','text36'};
%             
%                 for n=1:numel(decUIElements)
%                     eval(['app.' decUIElements{n} '.FontSize=macUIDecSize;'])
%                 end
%             
%                 titleUIElements={'uipanel13','uipanel6','uipanel7','uipanel8','uipanel9',...
%                 'uipanel5','uipanel1','uipanel15','uipanel10','uipanel6','uipanel16','csParserPanel'};
%             
%                 for n=1:numel(titleUIElements)
%                     eval(['app.' titleUIElements{n} '.FontSize=macHeaderSize;'])
%                 end
%             
%                 refreshWSVarsBtn_Callback(hObject, eventdata, handles);
%                 guidata(hObject, handles);
        end

        % Value changed function: axonalROIs_DisplayToggle
        function axonalROIs_DisplayToggle_Callback(app, event)
                genericDispalyTypeToggle(hObject, eventdata, handles)
                guidata(hObject, handles);
        end

        % Value changed function: boutonROIs_DisplayToggle
        function boutonROIs_DisplayToggle_Callback(app, event)
                genericDispalyTypeToggle(hObject, eventdata, handles)
                guidata(hObject, handles);
        end

        % Value changed function: corThresholdEntry
        function corThresholdEntry_Callback(app, event)
            
                roiDisplaySlider_Callback(hObject, eventdata, handles)
        end

        % Button pushed function: deleteFlagged
        function deleteFlagged_Callback(app, event)
                %
                % curROIType=evalin('base','dispROIString');
                % evalin('base',[curROIType ]);
            
            
                refreshWSVarsBtn_Callback(hObject, eventdata, handles)
                guidata(hObject, handles);
                somaRoisDisplayToggle_Callback(hObject, eventdata, handles)
        end

        % Button pushed function: deleteSelection
        function deleteSelection_Callback(app, event)
                selections = get(app.workspaceVarBox,'String');
                selectionsIndex = get(app.workspaceVarBox,'Value');
                selectedItem=selections{selectionsIndex};
                evalin('base',['clear ' selectedItem]);
                refreshWSVarsBtn_Callback(hObject, eventdata, handles)
            
                guidata(hObject, handles);
        end

        % Value changed function: dendriticROIs_DisplayToggle
        function dendriticROIs_DisplayToggle_Callback(app, event)
                genericDispalyTypeToggle(hObject, eventdata, handles)
                guidata(hObject, handles);
        end

        % Button pushed function: diskExtractButton
        function diskExtractButton_Callback(app, event)
            
                set(app.extractFeedbackString,'String','Extracting ...')
                pause(0.00000001);
                guidata(hObject, handles);
            
                imPath=evalin('base','metaData.importPath;');
                importType=evalin('base','metaData.importType;');
            
                % get images
                firstIm=str2num(get(app.firstImageEntry,'string'));
                endIm=str2num(get(app.endImageEntry,'string'));
            
                skipImagesToggle=get(app.diskExtractSkipByToggle,'Value');
                binImagesToggle=get(app.binByToggle,'Value');
            
                if binImagesToggle
                    binFac=str2num(get(app.binByEntry,'String'));
                else
                    binFac=1;
                end
            
                if skipImagesToggle==1
                    skipF=str2num(get(app.diskExtractSkipByEntry,'String'));
                else
                    skipF=1;
                end
            
                imRange=firstIm:skipF:endIm;
                imCount=numel(imRange);


                % we set it up differently depending on if folder, mptiff or hdf.
                if importType==0 %folder of files
                    fileList=evalin('base','metaData.filteredFiles;');
                    imageSize=evalin('base','metaData.imSize;');
                    tP=evalin('base','metaData.importPath;');
                    tH=evalin('base','metaData.importPath');
                    tDS_select=[];
                    hdfSize=1;
            
                elseif importType==2 % hdf
                    hdfSize=evalin('base','metaData.hdfSize;');
                    imageSize=[hdfSize(1),hdfSize(2)];
                    tP=evalin('base','metaData.importPath;');
                    tH=evalin('base','metaData.hdfFile;');
                    tDS_select=evalin('base','metaData.tDS_select;');
                else
                end
            
            
                disp(['after skiping you will extract from ' num2str(imCount) ' images'])
            
                % ************ handle concatination of roi types
                roiStringMap={'somaticROIs','dendriticROIs','axonalROIs','boutonROIs','neuropilROIs','vesselROIs','redSomaticROIs'};
                roiToggleTruth=[get(app.somaExtractCheck,'Value'),get(app.dendriteExtractCheck,'Value'),...
                    get(app.axonExtractCheck,'Value'),get(app.boutonExtractCheck,'Value'),...
                    get(app.neuropilExtractCheck,'Value'),get(app.vascularExtractCheck,'Value'),...
                    get(app.redSomaticExtractCheck,'Value')];
            
                warnBit=1;
                rois=[];
            
                disp(numel(roiToggleTruth))
                for n=1:numel(roiToggleTruth)
                    if roiToggleTruth(n)==1
                        warnBit=0;      % if anything is selected flip the warning to 0.
                        roisToMap=evalin('base', [roiStringMap{n} ';']);
                        rois=[rois roisToMap];
                    else
                    end
                end
            
            
                for n=1:numel(rois)
                    roiStack(:,:,n)=rois{n};
                end
                assignin('base','roiStack',roiStack);
            
                if warnBit==1
                    disp('no rois')
                end
                % ************ end handle concatination of roi types
            
            
                tempF=zeros(numel(rois),imCount);
                regFlag=get(app.diskRegFlag,'Value');
                disp(regFlag)
                disp(['about to extract, this should take ~ ' ...
                	num2str((numel(rois)*.0004*imCount)./60) ' minutes'])
                cc=clock;
                disp(['started at ' num2str(cc(4)) ':'  num2str(cc(5))])
                tic
                disp('extracting')
            
                if importType==2
                    tP=evalin('base','metaData.importPath');
                    tH=evalin('base','metaData.hdfFile');
                    tDS_select=evalin('base','metaData.tDS_select');
                else
                end
            
                if regFlag==1
                    template=double(evalin('base','regTemplate'));
                    regTransforms=zeros(4,imCount);
            %         fTemplate=fft2(template);
                else
                end
            
                parfor n=1:numel(imRange)
                    try
                        tic
                        % import the image
                        if importType==2
                            impImage=h5read([tP tH],['/' tDS_select],[1 1 n],[hdfSize(1) hdfSize(2) 1]);
                        else
                            impImage=imread([imPath filesep fileList(n).name]);
                            tSize = numel(size(impImage));
                            if tSize == 3
                                impImage = squeeze(impImage(:,:,1));
                            else
                            end
                        end
            
                        if binImagesToggle
                            impImage=binImages(impImage,binFac);
                            % assignin('base','dImpImage',impImage);
                            % debug
                        else
                        end
            
            
                        % register it (if we want).
                        disp(regFlag)
                        if regFlag==1
                            [out1,out2]=dftregistration(fTemplate,fft2(impImage),50);
                            regTransforms(:,n)=out1;
                            impImage=abs(ifft2(out2));
                        else
                        end
            
            
                        %***** vectorized slower
                        ff=double(impImage).*roiStack;
                        ffMu=squeeze(sum(sum(ff))./sum(sum(ff ~= 0)));
                        tempF(:,n)=ffMu;
                        %****
            
            %             for k=1:size(roiStack,3)
            %               aa(k)=mean(impImage(roiStack(:,:,q)==1));
            %             end
            %             tempF(:,n)=aa(k);
            
            
                        % if mod(n,250)==0
                        %     set(app.diskExtractButton,'String',[num2str(n) '/' num2str(numel(imRange))])
                        %     pause(0.00000000000000001)
                        %     guidata(hObject, handles);
                        % else
                        % end
                        dTm(n)=toc;
                    catch
                        disp('something went wrong; most likley cause is you were extracting from a jump drive that is unplugged.')
            %             break
                    end
                end


                eT=toc;
                disp(['done extracting, this took ' num2str(eT./60) ' minutes'])
            
                for n=1:numel(roiToggleTruth)
                    if roiToggleTruth(n)==1
                        roisToMapCount=evalin('base', ['numel(' roiStringMap{n} ')']);
                        assignin('base',[roiStringMap{n}(1:end-4) 'F'],tempF(1:roisToMapCount,:));
                        tempF(1:roisToMapCount,:)=[];
                    else
                    end
                end
                assignin('base','dTm',dTm);
            
                set(app.diskExtractButton,'String','Disk Extract')
                set(app.extractFeedbackString,'String','')
                guidata(hObject, handles);
        end

        % Value changed function: displayedROICounter
        function displayedROICounter_Callback(app, event)
                input = str2num(get(hObject,'String'));
                minVal=get(app.roiDisplaySlider, 'Min');
                maxVal=get(app.roiDisplaySlider, 'Max');
            
                %checks to see if input is empty. if so, default input1_editText to zero
                if (isempty(input))
                     set(hObject,'String','1')
                end
            
                if input<1
                    input=1;
                    set(app.displayedROICounter,'Value',input);
                else
                end
            
                if input<minVal
                    input=minVal;
                    set(app.displayedROICounter,'Value',input);
                elseif input>maxVal
                    input=maxVal;
                    set(app.displayedROICounter,'Value',input);
                else
                end
            
                set(app.roiDisplaySlider,'Value',input);
            
                roiDisplaySlider_Callback(hObject, eventdata, handles)
                guidata(hObject, handles);
        end

        % Button pushed function: extractButton
        function extractButton_Callback(app, event)
            
                selections = get(app.workspaceVarBox,'String');
                selectionsIndex = get(app.workspaceVarBox,'Value');
                selectStack=selections{selectionsIndex};
            
            
                dStackSize=evalin('base',['size(' selectStack ');']);
            
                if dStackSize(3)>1
            
                % ************ handle concatination of roi types
                roiStringMap={'somaticROIs','dendriticROIs','axonalROIs','boutonROIs','neuropilROIs','vesselROIs','redSomaticROIs'};
                roiToggleTruth=[get(app.somaExtractCheck,'Value'),get(app.dendriteExtractCheck,'Value'),...
                    get(app.axonExtractCheck,'Value'),get(app.boutonExtractCheck,'Value'),...
                    get(app.neuropilExtractCheck,'Value'),get(app.vascularExtractCheck,'Value'),...
                    get(app.redSomaticExtractCheck,'Value')];
            
                rois=[];   % we will map in the selected rois into this
                warnBit=1;
                for n=1:numel(roiToggleTruth)
                    if roiToggleTruth(n)==1
                        warnBit=0;      % if anything is selected flip the warning to 0.
                        roisToMap=evalin('base', roiStringMap{n});
                        rois=[rois roisToMap];
                    else
                    end
                end
            
                if warnBit==1
                    disp('no rois')
                end
                % ************ end handle concatination of roi types
            
                %--- extract
                set(app.extractButton,'string','running','ForegroundColor','red','enable','off');
                pause(0.00000001);
                guidata(hObject, handles);
            
                sED=zeros(numel(rois),dStackSize(3));
                medianFlag=get(app.medianExtractToggle,'Value');


                tic
            
                for n=1:dStackSize(3)
                    for q=1:numel(rois)
                        aIm=double(evalin('base',[selectStack '(:,:,' num2str(n) ')']));
                        if medianFlag==0
                        	sED(q,n)=mean(aIm(rois{q}(:,:)==1));
                    	elseif medianFlag==1
                        	sED(q,n)=median(aIm(rois{q}(:,:)==1));
                        end
                    end
                    if mod(n,500)==0
                        set(app.extractButton,'String',[num2str(n) '/' num2str(dStackSize(3))])
                        pause(0.00000000000000001)
                        guidata(hObject, handles);
                    else
                    end
                end
            
                aa=toc;
                set(app.extractButton,'String','Extract')
            
                %--- end extract
            
                % ************  now we need to map the extracted values to the original roi types
                for n=1:numel(roiToggleTruth)
                    if roiToggleTruth(n)==1
                        roisToMapCount=evalin('base', ['numel(' roiStringMap{n} ')']);
                        assignin('base',[roiStringMap{n}(1:end-4) 'F'],sED(1:roisToMapCount,:));
                        sED(1:roisToMapCount,:)=[];
                    else
                    end
                end
            
            
                set(app.extractButton,'string','Extract','ForegroundColor','black','enable','on');
            
                % Update handles structure
                set(app.extractFeedbackString,'String','')
                set(app.extractButton,'string','Extract','ForegroundColor','black','enable','on');
            
                % Update handles structure
                refreshWSVarsBtn_Callback(hObject, eventdata, handles)
                guidata(hObject, handles);
                else
                end
        end

        % Button pushed function: flagROIButton
        function flagROIButton_Callback(app, event)
            
                sROI=str2num(get(app.displayedROICounter,'String'));
                bE=evalin('base','exist(''dispROIString'');');
                if bE==1
                    dispString=evalin('base','dispROIString;');
                    tStr=['flagged_' dispString];
                    fE=evalin('base',['exist([''' tStr '''])']);
            
                    if fE
                        curFlagged=evalin('base',['flagged_' dispString]);
                        curFlagged=[curFlagged; sROI];
                        assignin('base',['flagged_' dispString],curFlagged)
                    else
                        curFlagged=sROI;
                        assignin('base',['flagged_' dispString],curFlagged)
                    end
                else
                end
            
                rN=fix(str2double(get(app.displayedROICounter,'String')))+1;
                set(app.roiDisplaySlider,'Value',rN)
                guidata(hObject, handles);
                roiDisplaySlider_Callback(hObject, eventdata, handles)
        end

        % Button pushed function: groupMarkerBtn
        function groupMarkerBtn_Callback(app, event)
            
                gNum=fix(str2num(get(app.groupCounter,'String')))+1;
                % gIs=str2num(get(app.relatedCellsReturn,'String'));
                gIs=get(app.relatedCellsReturn,'String');
                gVs=str2num(get(app.relatedValuesReturn,'String'));
                gDs=str2num(get(app.relatedDistReturn,'String'));
            
                if numel(gIs)>1
                    assignin('base','tIs',gIs);
                    assignin('base','tVs',gVs);
                    assignin('base','tDs',gDs);
                    evalin('base',['tgROIs{' num2str(gNum) ',1}=tIs;,clear tIs']);
                    evalin('base',['tgScores{' num2str(gNum) ',1}=tVs;,clear tVs']);
                    evalin('base',['tgDists{' num2str(gNum) ',1}=tDs;,clear tDs']);
                    set(app.groupCounter,'String',num2str(gNum))
                    refreshWSVarsBtn_Callback(hObject, eventdata, handles)
                    guidata(hObject, handles);
                else
                end
            
            
                rN=fix(str2double(get(app.displayedROICounter,'String')))+1;
                set(app.roiDisplaySlider,'Value',rN)
                guidata(hObject, handles);
                roiDisplaySlider_Callback(hObject, eventdata, handles)
        end

        % Button pushed function: importerBtn
        function importerBtn_Callback(app, event)
            
                evalin('base','importer')
        end

        % Button pushed function: keepFirstBtn
        function keepFirstBtn_Callback(app, event)
            
            
                related=get(app.relatedCellsReturn,'String');
                rCell=strsplit(related,',');
                rCell=rCell(2:end);
                tString=[];
                for n=1:numel(rCell)
                    selectedSplit=strsplit(rCell{n},'_');
                    tString{n}=selectedSplit{1};
                    tID{n}=str2double(selectedSplit{2});
                end
            
            
                roiNumList=cell2mat(tID);
                % assignin('base','troiNumList',roiNumList)
                assignin('base','tString',tString)
            
            
                try
                    unStr=cellfun(@unique,{dtString},'UniformOutput',0);
                    for p=1:numel(unStr{1})
                        for n=1:numel(rCell)
                            kIDs=cell2mat(tID(tString{n}==unStr{1}{n}));
                        end
                        evalin('base',[tString{n} 'ROI_PixelLists([' num2str(kIDs) '])=[]'])
                        evalin('base',[tString{n} 'ROIBoundaries([' num2str(kIDs) '])=[]'])
                        evalin('base',[tString{n} 'ROICenters([' num2str(kIDs) '])=[];'])
                        evalin('base',[tString{n} 'ROIs([' num2str(kIDs) '])=[];'])
                        evalin('base',[tString{n} 'RoiCounter=numel(' uniqueTypes{n} 'ROIs);'])
                    end
                catch
                end
                guidata(hObject, handles);
        end

        % Button pushed function: killFirstBtn
        function killFirstBtn_Callback(app, event)
                related=str2num(get(app.relatedCellsReturn,'String'));
            
                deleteROI(related(1),typeList,scopeString)
            
                tKS=num2str(related(1));
                killString=['[' tKS ']'];
            
            
                evalin('base',['somaticF(' killString ',:)=[];']);
                evalin('base','somaticRoiCounter=size(somaticF,1);');
                evalin('base',['somaticROI_PixelLists(' killString ')=[];']);
                evalin('base',['somaticROIBoundaries(' killString ')=[];']);
                evalin('base',['somaticROICenters(' killString ')=[];']);
                evalin('base',['somaticROIs(' killString ')=[];']);
            
                refreshWSVarsBtn_Callback(hObject, eventdata, handles)
                guidata(hObject, handles);
                % somaRoisDisplayToggle_Callback(hObject, eventdata, handles)
                advance=0;
            
                rN=fix(str2double(get(app.displayedROICounter,'String')))+advance;
                set(app.roiDisplaySlider,'Value',rN)
                guidata(hObject, handles);
                roiDisplaySlider_Callback(hObject, eventdata, handles)
                somaRoisDisplayToggle_Callback(hObject, eventdata, handles)
        end

        % Button pushed function: killSelectedBtn
        function killSelectedBtn_Callback(app, event)
            
                selections = get(app.roiSelector,'String');
                selectionsIndex = get(app.roiSelector,'Value');
                selectedSplit=strsplit(selections{selectionsIndex},'_');
                tString=selectedSplit(1);
                tID=str2double(selectedSplit(2));
                deleteROI(tID,tString);
                disp([tString{1} '_'  num2str(tID)])
                evalin('base',['try,killedROIs = horzcat(killedROIs,"' tString{1} '_' num2str(tID) '");, catch, killedROIs = {};,killedROIs = horzcat(killedROIs,"' tString{1} '_'  num2str(tID) '");,end']);
            
                genericDispalyTypeToggle(hObject, eventdata, handles)
            
                if selectionsIndex>1
                    nV=selectionsIndex;
                else
                    nV=1;
                end
            
                set(app.roiSelector,'Value',nV);
                roiSelector_Callback(hObject, eventdata, handles)
                refreshWSVarsBtn_Callback(hObject, eventdata, handles)
                guidata(hObject, handles);
        end

        % Button pushed function: loadCSHDF
        function loadCSHDF_Callback(app, event)
                try
                    evalin('base','bData=csParser2();')
                    bvars = evalin('base','fieldnames(bData)');
                    set(app.csListbox,'String',bvars)
                    guidata(hObject, handles);
                catch
                    guidata(hObject, handles);
                end
        end

        % Button pushed function: movingAvgBtn
        function movingAvgBtn_Callback(app, event)
            
                mvWin=fix(str2double(get(app.smoothWindowEntry,'String')));
                selections = get(app.workspaceVarBox,'String');
                selectionsIndex = get(app.workspaceVarBox,'Value');
                selectStack=selections{selectionsIndex};
                aa=evalin('base',selectStack);
                bb=nPointMean(aa',mvWin);
                clear aa
                assignin('base',selectStack,bb');
        end

        % Button pushed function: neuropilCorrectBtn
        function neuropilCorrectBtn_Callback(app, event)
            
            aa=evalin('base','somaticF');
            bb=evalin('base','neuropilF');
            scF = str2num(get(app.neuropilScaleEntry,'String'));
            % once you have somaticF data and neuropilF data you can correct.
            assignin('base','somaticF_original',aa);
            disp(scF)
            aa = aa - (scF*bb);
            
            
            % add an offset, so we don't get negative values for df/f
            % the offset will affect df/f so we will rescale later by the error.
            % the error is the ratio of the original mean and the scalar.
            
            assignin('base','somaticF',aa');
            evalin('base',"somaticF = somaticF';")
            
            clear aa
        end

        % Value changed function: neuropilROIs_DisplayToggle
        function neuropilROIs_DisplayToggle_Callback(app, event)
            
                genericDispalyTypeToggle(hObject, eventdata, handles)
                guidata(hObject, handles);
        end

        % Button pushed function: oopsiBtn
        function oopsiBtn_Callback(app, event)
            
                evalin('base','frameInterval = 0.100151818131243;')
                evalin('base','eventThreshold = 0.02;')
                evalin('base','noiseFac = -20;');
                evalin('base','for n=1:size(somaticF_DF,1),[somaticF_DF_Clean(n,:),eventEstimate(n,:),dOptions{n}]=deconvolveCa(transpose(somaticF_DF(n,:)),''foopsi'',''ar1'', ''smin'', noiseFac);,somaticF_DF_Events(n,:)=zeros(size(eventEstimate(n,:)));,tInds = find(eventEstimate(n,:)>=eventThreshold);,somaticF_DF_Events(n,tInds)=1;,somaticF_DF_Event_Times{n}=find(diff(somaticF_DF_Events(n,:)==1))*frameInterval;,somaticF_DF_Event_ITIs{n}=diff(somaticF_DF_Event_Times{n});,somaticF_DF_Event_Freq{n}=1./somaticF_DF_Event_ITIs{n};,somaticF_DF_Event_MuFreq(:,n)=nanmean(1./somaticF_DF_Event_ITIs{n});,somaticF_DF_Event_MedFreq(:,n)=nanmedian(1./somaticF_DF_Event_ITIs{n});,end')
                disp("oopsi")


            refreshWSVarsBtn_Callback(hObject, eventdata, handles)
            guidata(hObject, handles);
        end

        % Button pushed function: plotCSVar
        function plotCSVar_Callback(app, event)
            
                selections = get(app.csListbox,'String');
                selectionsIndex = get(app.csListbox,'Value');
                selectTrace=selections{selectionsIndex};
                tTrace=evalin('base',['bData.' selectTrace]);
                csTime=evalin('base','bData.sessionTime');
                try
                    scaleEntry=str2num(get(app.plotScaleEntry,'String'));
                catch
                    scaleEntry=1;
                end
            
                normTrace=get(app.plotNormToggle,'Value');
                if normTrace==1
                    tTrace=scaleEntry*((tTrace-min(tTrace))./max(tTrace-min(tTrace)));
            
                else
                end
            
                axes(app.traceDisplay)
                holdLast=get(app.holdCurrentROILinesToggle,'Value');
                if holdLast
                    lstAx=gca;
                    lastLines=lstAx.Children;
                    for n=1:numel(lastLines)
                        lastLine(n).Color=[1,0,0];
                    end
                    hold all
                else
                    hold off
                end
            
                mainColor=[0,0,0];
                exceptColor=[0,0.6,0.2];
                try
                    plot(csTime,tTrace(1,:)','Color',mainColor,'LineWidth',1.1);
                catch
                    plot(tTrace(1,:)','Color',exceptColor,'LineWidth',1.1);
                end
                lstAx=gca;
                h=lstAx.Children;
                if numel(h)>1
                    for k=2:numel(h)
                        h(k).Color=[1,0,0];
                    end
                else
                end
                hold off
                guidata(hObject, handles);
        end

        % Button pushed function: rcBtn_generic
        function rcBtn_generic_Callback(app, event)
            
                curROIType=evalin('base','dispROIString');
            
                if strcmp(curROIType,'somaticROI')
                    disp('It is already a soma.')
                else
                    curNum=fix(str2double(get(app.displayedROICounter,'String')));
                    g=evalin('base','exist(''somaticRoiCounter'')');
            
                end
            
                refreshWSVarsBtn_Callback(hObject, eventdata, handles)
                guidata(hObject, handles);
        end

        % Value changed function: redSomaticROIs_DisplayToggle
        function redSomaticROIs_DisplayToggle_Callback(app, event)
                genericDispalyTypeToggle(hObject, eventdata, handles)
                guidata(hObject, handles);
        end

        % Button pushed function: refreshCSVarBtn
        function refreshCSVarBtn_Callback(app, event)
            
                try
                    bvars = evalin('base','fieldnames(bData)');
                    set(app.csListbox,'String',bvars)
                catch
                end
                guidata(hObject, handles);
        end

        % Button pushed function: refreshWSVarsBtn
        function refreshWSVarsBtn_Callback(app, event)
            
                vars = evalin('base','who');
                set(app.workspaceVarBox,'String',vars)
        end

        % Value changed function: roiDisplaySlider
        function roiDisplaySlider_Callback(app, event)
                % make sure the slider is caught up
                sliderValue = fix(get(app.roiDisplaySlider,'Value'));
                if sliderValue<1
                    sliderValue=1;
                else
                end
            
                % make sure the slider text entry is caught up
                set(app.displayedROICounter,'String', num2str(sliderValue));
                set(app.roiSelector,'Value',sliderValue);
                guidata(hObject, handles);
                plotROI(hObject, eventdata, handles)
        end

        % Button pushed function: roiMakerBtn
        function roiMakerBtn_Callback(app, event)
            
                evalin('base','roiMaker')
        end

        % Value changed function: roiSelector
        function roiSelector_Callback(app, event)
            
                roiSelectionNum=get(app.roiSelector,'Value');
            
                % make sure the slider is caught up
                set(app.roiDisplaySlider,'Value',roiSelectionNum);
                sliderValue = get(app.roiDisplaySlider,'Value');
            
                % make sure the slider text entry is caught up
                set(app.displayedROICounter,'String', num2str(sliderValue));
            
                guidata(hObject, handles)
                plotROI(hObject, eventdata, handles)
        end

        % Button pushed function: saveTracesPDFBtn
        function saveTracesPDFBtn_Callback(app, event)
            
                tnum=str2double(get(app.displayedROICounter,'String'));
                hh=evalin('base','curh');
                mi=evalin('base','curHMask');
                miX=mi.XData;
                miY=mi.YData;
                mi.XData=miY;
                mi.YData=miX;


                tFig=figure('visible','off');
                subplot(6,2,[5 6 7 8 9 10 11 12])
                ta=gca;
                % hh(1,end).Color=[0,0,0];
                copyobj(hh,ta);
                ta.Box='off';
                ta.TickDir='out';
            
                subplot(6,2,[2 4])
                tb=gca;
                copyobj(mi,tb);
            
                tb.YTick=[];
                tb.XTick=[];
                tb.Clipping='off';
                tb.Visible='off';
                tb.YDir='reverse';
            
                % tFig.PaperPositionMode='manual';
                orient(tFig,'portrait');
                print(tFig,['reladteROIs_' num2str(tnum) '.pdf'],'-dpdf','-painters','-r300');
                clear tFig
                mi.XData=miX;
                mi.YData=miY;
        end

        % Button pushed function: setImageTime
        function setImageTime_Callback(app, event)
            
                selections = get(app.workspaceVarBox,'String');
                selectionsIndex = get(app.workspaceVarBox,'Value');
                selectTrace=selections{selectionsIndex};
                imTime=evalin('base',selectTrace);
                assignin('base','imTime',imTime);
                refreshWSVarsBtn_Callback(hObject, eventdata, handles)
                guidata(hObject, handles);
        end

        % Value changed function: showCorrelatedToggle
        function showCorrelatedToggle_Callback(app, event)
            
                roiDisplaySlider_Callback(hObject, eventdata, handles)
        end

        % Button pushed function: slidingBaselineBtn
        function slidingBaselineBtn_Callback(app, event)
            
            
                pause(0.0000000000001);
                guidata(hObject, handles);
            
                frmWinSize=str2num(get(app.movBaselineWinEntry,'String'));
            
                typeID=get(app.typeSelectorMenu,'Value');
                tString=get(app.typeSelectorMenu,'String');
            
                selectedType=tString{typeID};
                set(app.feedbackString,'String',['Baselining: ' selectedType])
                pause(0.0000000000001);
                guidata(hObject, handles);
                try
                    ogData = evalin('base','somaticF_original');
                catch
                    evalin('base','somaticF_original = somaticF;');
                    ogData = evalin('base','somaticF_original');
                end
                fData = evalin('base',[selectedType 'F']);
                fData = fData + 10000;
                scaleError = 10000/nanmean(nanmean(ogData));
            
                fBLs = zeros(size(fData));
            
                blCutOffs = computeQunatileCutoffs(fData); %F_BLCutOffs
            
            
                fBLs=slidingBaseline(fData,frmWinSize,blCutOffs);
            
                assignin('base',[selectedType 'F_nonBL'],fData)
            
                fData = fData - fBLs;
                dfTemp = fData./fBLs;
            
            
                assignin('base',[selectedType 'F'],fData)
                assignin('base',[selectedType 'F_DF'],dfTemp*scaleError)
            
                set(app.feedbackString,'String',['Done With: ' selectedType])
                pause(0.0000000000001);
                guidata(hObject, handles);
            
                refreshWSVarsBtn_Callback(hObject, eventdata, handles)
                guidata(hObject, handles);
        end

        % Value changed function: somaticROIs_DisplayToggle
        function somaticROIs_DisplayToggle_Callback(app, event)
                genericDispalyTypeToggle(app,event);
                guidata(hObject, handles);
        end

        % Value changed function: sortAscend
        function sortAscend_Callback(app, event)
            
                roiDisplaySlider_Callback(hObject, eventdata, handles)
        end

        % Value changed function: sortByMenu
        function sortByMenu_Callback(app, event)
            
                roiDisplaySlider_Callback(hObject, eventdata, handles)
        end

        % Button pushed function: upSampleTrimImages
        function upSampleTrimImages_Callback(app, event)
                try
                    absTime=evalin('base','imTime');
                    bTime=evalin('base','bData.sessionTime');
                catch
                    return
                end
            
                imEnd=absTime(end);
                bhEnd=bTime(end);
            
                imOver=find(absTime>bhEnd);
                try
                    imTrim=imOver(1)-1;
                catch
                    imTrim=numel(absTime);
                end
            
                assignin('base','imTrim',imTrim);
            
                % this should always be true.
                if imEnd>bhEnd
                    absTime=absTime(1:imTrim);
                    assignin('base','imTime',absTime);
                    assignin('base','imTrim',imTrim);
                else
                end
            
                upsampleList={}
                upCount=1;
                try
                    evalin('base','somaticF=somaticF(:,1:imTrim);')
                    upsampleList{upCount}='somaticF'
                    upCount=upCount+1;
                catch
                end
            
                try
                    evalin('base','somaticF_DF=somaticF_DF(:,1:imTrim);')
                    upsampleList{upCount}='somaticF_DF'
                    upCount=upCount+1;
                catch
                end
            
                try
                    evalin('redSomaticF=redSomaticF(:,1:imTrim);')
                    upsampleList{upCount}='redSomaticF'
                    upCount=upCount+1;
                catch
                end
            
                try
                    evalin('redSomaticF_DF=redSomaticF_DF(:,1:imTrim);')
                    upsampleList{upCount}='redSomaticF_DF'
                    upCount=upCount+1;
                catch
                end
            
            
                for n=1:numel(upsampleList)
                    evalin('base',['rsDataDF=rsCaData(transpose(' upsampleList{n}  '),transpose(imTime),bData.sessionTime);'])
                    evalin('base','rsDataDF(find(isnan(rsDataDF)==1))=0;')
                    evalin('base',[upsampleList{n} '=transpose(rsDataDF);, clear rsDataDF'])
                end
                assignin('base','imTime',transpose(bTime));
        end

        % Value changed function: vesselROIs_DisplayToggle
        function vesselROIs_DisplayToggle_Callback(app, event)
                genericDispalyTypeToggle(hObject, eventdata, handles)
                guidata(hObject, handles);
        end

        % Value changed function: yHighTrace
        function yHighTrace_Callback(app, event)
            
                roiDisplaySlider_Callback(hObject, eventdata, handles)
        end

        % Value changed function: yLowTrace
        function yLowTrace_Callback(app, event)
            
                roiDisplaySlider_Callback(hObject, eventdata, handles)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'extractor';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'ROIs to Extract';
            app.extractFeedbackString = uilabel(app.uipanel1);
            app.extractFeedbackString.Text = '';
            app.redSomaticExtractCheck = uicheckbox(app.uipanel1);
            app.redSomaticExtractCheck.Text = 'Red Somatic';
            app.somaExtractCheck = uicheckbox(app.uipanel1);
            app.somaExtractCheck.Text = 'Somatic';
            app.dendriteExtractCheck = uicheckbox(app.uipanel1);
            app.dendriteExtractCheck.Text = 'Dendritic';
            app.axonExtractCheck = uicheckbox(app.uipanel1);
            app.axonExtractCheck.Text = 'Axonal';
            app.boutonExtractCheck = uicheckbox(app.uipanel1);
            app.boutonExtractCheck.Text = 'Bouton';
            app.vascularExtractCheck = uicheckbox(app.uipanel1);
            app.vascularExtractCheck.Text = 'Vascular';
            app.neuropilExtractCheck = uicheckbox(app.uipanel1);
            app.neuropilExtractCheck.Text = 'Neuropil';
            app.extractButton = uibutton(app.uipanel1, 'push');
            app.extractButton.ButtonPushedFcn = createCallbackFcn(app, @extractButton_Callback, true);
            app.extractButton.Text = 'Extract';
            app.medianExtractToggle = uicheckbox(app.uipanel1);
            app.medianExtractToggle.Text = 'use median';
            app.roiDisplaySlider = uislider(app.figure1);
            app.roiDisplaySlider.Limits = [0 1];
            app.roiDisplaySlider.MajorTicks = [];
            app.roiDisplaySlider.ValueChangedFcn = createCallbackFcn(app, @roiDisplaySlider_Callback, true);
            app.roiDisplaySlider.MinorTicks = [];
            app.displayedROICounter = uieditfield(app.figure1, 'text');
            app.displayedROICounter.ValueChangedFcn = createCallbackFcn(app, @displayedROICounter_Callback, true);
            app.displayedROICounter.Value = '1';
            app.text1 = uilabel(app.figure1);
            app.text1.Text = 'ROI';
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = 'Correlations/Relations';
            app.corAxis = uiaxes(app.uipanel7);
            app.corAxis.XTick = zeros(0,3);
            app.corAxis.XTickLabel = {'0'; '0.5'; '1'};
            app.corAxis.YTick = zeros(0,6);
            app.corAxis.YTickLabel = {'0.0'; '0.2'; '0.4'; '0.6'; '0.8'; '1.0'};
            app.featureHist = uiaxes(app.uipanel7);
            app.featureHist.YTick = zeros(0,6);
            app.featureHist.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.featurePlot = uiaxes(app.uipanel7);
            app.featurePlot.XTick = zeros(0,3);
            app.featurePlot.XTickLabel = {'0'; '0.5'; '1'};
            app.featurePlot.YTick = zeros(0,6);
            app.featurePlot.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.showCorrelatedToggle = uicheckbox(app.uipanel7);
            app.showCorrelatedToggle.ValueChangedFcn = createCallbackFcn(app, @showCorrelatedToggle_Callback, true);
            app.showCorrelatedToggle.Text = 'show related?';
            app.corThresholdEntry = uieditfield(app.uipanel7, 'text');
            app.corThresholdEntry.ValueChangedFcn = createCallbackFcn(app, @corThresholdEntry_Callback, true);
            app.corThresholdEntry.Value = '0.8';
            app.text9 = uilabel(app.uipanel7);
            app.text9.Text = 'thresh';
            app.relatedCellsReturn = uieditfield(app.uipanel7, 'text');
            app.relatedCellsReturn.Value = 'c1,c2,c3';
            app.text18 = uilabel(app.uipanel7);
            app.text18.Text = 'Related Cells:';
            app.groupMarkerBtn = uibutton(app.uipanel7, 'push');
            app.groupMarkerBtn.ButtonPushedFcn = createCallbackFcn(app, @groupMarkerBtn_Callback, true);
            app.groupMarkerBtn.Text = 'mark group';
            app.groupCounter = uieditfield(app.uipanel7, 'text');
            app.groupCounter.Value = '0';
            app.text19 = uilabel(app.uipanel7);
            app.text19.Text = 'group';
            app.relatedValuesReturn = uieditfield(app.uipanel7, 'text');
            app.relatedValuesReturn.Value = 'v1,v2,v3';
            app.relatedValuesSt = uilabel(app.uipanel7);
            app.relatedValuesSt.Text = 'Cor Values:';
            app.rcBtn_generic = uibutton(app.uipanel7, 'push');
            app.rcBtn_generic.ButtonPushedFcn = createCallbackFcn(app, @rcBtn_generic_Callback, true);
            app.rcBtn_generic.Enable = 'off';
            app.rcBtn_generic.Text = 'recatagorize';
            app.relatedDistReturn = uieditfield(app.uipanel7, 'text');
            app.relatedDistReturn.Value = 'd1,d2,d3';
            app.text22 = uilabel(app.uipanel7);
            app.text22.Text = 'Dist Values:';
            app.keepFirstBtn = uibutton(app.uipanel7, 'push');
            app.keepFirstBtn.ButtonPushedFcn = createCallbackFcn(app, @keepFirstBtn_Callback, true);
            app.keepFirstBtn.Text = 'keep first';
            app.killSelectedBtn = uibutton(app.uipanel7, 'push');
            app.killSelectedBtn.ButtonPushedFcn = createCallbackFcn(app, @killSelectedBtn_Callback, true);
            app.killSelectedBtn.Text = 'kill selection';
            app.relatedPixelCountReturn = uieditfield(app.uipanel7, 'text');
            app.relatedPixelCountReturn.Value = 'p1,p2,p3';
            app.text24 = uilabel(app.uipanel7);
            app.text24.Text = 'Pixel Count';
            app.sortByMenu = uidropdown(app.uipanel7);
            app.sortByMenu.Items = {'Correlations', 'ROIs', 'Distances', 'PixelCounts'};
            app.sortByMenu.ValueChangedFcn = createCallbackFcn(app, @sortByMenu_Callback, true);
            app.sortByMenu.Value = 'Correlations';
            app.sortAscend = uicheckbox(app.uipanel7);
            app.sortAscend.ValueChangedFcn = createCallbackFcn(app, @sortAscend_Callback, true);
            app.sortAscend.Text = 'ascending?';
            app.sortAscend.Value = true;
            app.killGroupBtn = uibutton(app.uipanel7, 'push');
            app.killGroupBtn.Text = 'kill group';
            app.killFirstBtn = uibutton(app.uipanel7, 'push');
            app.killFirstBtn.ButtonPushedFcn = createCallbackFcn(app, @killFirstBtn_Callback, true);
            app.killFirstBtn.Text = 'kill first';
            app.text27 = uilabel(app.uipanel7);
            app.text27.Text = 'sort by:';
            app.pushbutton38 = uibutton(app.uipanel7, 'push');
            app.pushbutton38.Text = 'refresh';
            app.flagROIButton = uibutton(app.uipanel7, 'push');
            app.flagROIButton.ButtonPushedFcn = createCallbackFcn(app, @flagROIButton_Callback, true);
            app.flagROIButton.Enable = 'off';
            app.flagROIButton.Text = 'flag ROI';
            app.deleteFlagged = uibutton(app.uipanel7, 'push');
            app.deleteFlagged.ButtonPushedFcn = createCallbackFcn(app, @deleteFlagged_Callback, true);
            app.deleteFlagged.Enable = 'off';
            app.deleteFlagged.Text = 'del flagged';
            app.saveTracesPDFBtn = uibutton(app.uipanel7, 'push');
            app.saveTracesPDFBtn.ButtonPushedFcn = createCallbackFcn(app, @saveTracesPDFBtn_Callback, true);
            app.saveTracesPDFBtn.Text = 'save pdf';
            app.uipanel8 = uipanel(app.figure1);
            app.uipanel8.Title = 'Graph Manipulations';
            app.text11 = uilabel(app.uipanel8);
            app.text11.Text = 'X Lims';
            app.text14 = uilabel(app.uipanel8);
            app.text14.Text = 'Y Lims';
            app.text15 = uilabel(app.uipanel8);
            app.text15.Text = 'low';
            app.text16 = uilabel(app.uipanel8);
            app.text16.Text = 'high';
            app.xLowTrace = uieditfield(app.uipanel8, 'text');
            app.xLowTrace.Value = '0';
            app.xHighTrace = uieditfield(app.uipanel8, 'text');
            app.xHighTrace.Value = '1000';
            app.yLowTrace = uieditfield(app.uipanel8, 'text');
            app.yLowTrace.ValueChangedFcn = createCallbackFcn(app, @yLowTrace_Callback, true);
            app.yLowTrace.Value = '0';
            app.yHighTrace = uieditfield(app.uipanel8, 'text');
            app.yHighTrace.ValueChangedFcn = createCallbackFcn(app, @yHighTrace_Callback, true);
            app.yHighTrace.Value = '5200';
            app.uipanel9 = uipanel(app.figure1);
            app.uipanel9.Title = 'Timeseries Operations';
            app.smoothWindowEntry = uieditfield(app.uipanel9, 'text');
            app.smoothWindowEntry.Value = '5';
            app.movingAvgBtn = uibutton(app.uipanel9, 'push');
            app.movingAvgBtn.ButtonPushedFcn = createCallbackFcn(app, @movingAvgBtn_Callback, true);
            app.movingAvgBtn.Text = 'Smooth';
            app.slidingBaselineBtn = uibutton(app.uipanel9, 'push');
            app.slidingBaselineBtn.ButtonPushedFcn = createCallbackFcn(app, @slidingBaselineBtn_Callback, true);
            app.slidingBaselineBtn.Text = 'Baseline';
            app.movBaselineWinEntry = uieditfield(app.uipanel9, 'text');
            app.movBaselineWinEntry.Value = '500';
            app.useQuantForDFToggle = uicheckbox(app.uipanel9);
            app.useQuantForDFToggle.Text = 'use quant threshold';
            app.useQuantForDFToggle.Value = true;
            app.typeSelectorMenu = uidropdown(app.uipanel9);
            app.typeSelectorMenu.Items = {'somatic', 'dendritic', 'axonal', 'bouton', 'redSomatic', 'neuropil', 'vessel'};
            app.typeSelectorMenu.Value = 'somatic';
            app.neuropilCorrectBtn = uibutton(app.uipanel9, 'push');
            app.neuropilCorrectBtn.ButtonPushedFcn = createCallbackFcn(app, @neuropilCorrectBtn_Callback, true);
            app.neuropilCorrectBtn.Text = 'Neuropil';
            app.neuropilScaleEntry = uieditfield(app.uipanel9, 'text');
            app.neuropilScaleEntry.Value = '0.8';
            app.feedbackString = uilabel(app.uipanel9);
            app.feedbackString.Text = '';
            app.oopsiBtn = uibutton(app.uipanel9, 'push');
            app.oopsiBtn.ButtonPushedFcn = createCallbackFcn(app, @oopsiBtn_Callback, true);
            app.oopsiBtn.Text = 'oopsi';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'ROI Types to Display';
            app.somaticROIs_DisplayToggle = uicheckbox(app.uipanel5);
            app.somaticROIs_DisplayToggle.ValueChangedFcn = createCallbackFcn(app, @somaticROIs_DisplayToggle_Callback, true);
            app.somaticROIs_DisplayToggle.Text = 'somatic';
            app.dendriticROIs_DisplayToggle = uicheckbox(app.uipanel5);
            app.dendriticROIs_DisplayToggle.ValueChangedFcn = createCallbackFcn(app, @dendriticROIs_DisplayToggle_Callback, true);
            app.dendriticROIs_DisplayToggle.Text = 'dendritic';
            app.axonalROIs_DisplayToggle = uicheckbox(app.uipanel5);
            app.axonalROIs_DisplayToggle.ValueChangedFcn = createCallbackFcn(app, @axonalROIs_DisplayToggle_Callback, true);
            app.axonalROIs_DisplayToggle.Text = 'axonal';
            app.boutonROIs_DisplayToggle = uicheckbox(app.uipanel5);
            app.boutonROIs_DisplayToggle.ValueChangedFcn = createCallbackFcn(app, @boutonROIs_DisplayToggle_Callback, true);
            app.boutonROIs_DisplayToggle.Text = 'bouton';
            app.vesselROIs_DisplayToggle = uicheckbox(app.uipanel5);
            app.vesselROIs_DisplayToggle.ValueChangedFcn = createCallbackFcn(app, @vesselROIs_DisplayToggle_Callback, true);
            app.vesselROIs_DisplayToggle.Text = 'vascular';
            app.redSomaticROIs_DisplayToggle = uicheckbox(app.uipanel5);
            app.redSomaticROIs_DisplayToggle.ValueChangedFcn = createCallbackFcn(app, @redSomaticROIs_DisplayToggle_Callback, true);
            app.redSomaticROIs_DisplayToggle.Text = 'red somatic';
            app.neuropilROIs_DisplayToggle = uicheckbox(app.uipanel5);
            app.neuropilROIs_DisplayToggle.ValueChangedFcn = createCallbackFcn(app, @neuropilROIs_DisplayToggle_Callback, true);
            app.neuropilROIs_DisplayToggle.Text = 'neuropil';
            app.plotAsDFToggle = uicheckbox(app.uipanel5);
            app.plotAsDFToggle.Text = 'use df/f?';
            app.cleanTraceToggle = uicheckbox(app.uipanel5);
            app.cleanTraceToggle.Text = 'clean?';
            app.uipanel13 = uipanel(app.figure1);
            app.uipanel13.Title = 'ROI Masks';
            app.roiMaskAxis = uiaxes(app.uipanel13);
            app.roiMaskAxis.XTick = zeros(0,3);
            app.roiMaskAxis.XTickLabel = {'0'; '0.5'; '1'};
            app.roiMaskAxis.YTick = zeros(0,6);
            app.roiMaskAxis.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.roiMaskAxis.Visible = 'off';
            app.uipanel15 = uipanel(app.figure1);
            app.uipanel15.Title = 'Workspace';
            app.workspaceVarBox = uilistbox(app.uipanel15);
            app.workspaceVarBox.Items = {'Variables'};
            app.workspaceVarBox.Value = 'Variables';
            app.refreshWSVarsBtn = uibutton(app.uipanel15, 'push');
            app.refreshWSVarsBtn.ButtonPushedFcn = createCallbackFcn(app, @refreshWSVarsBtn_Callback, true);
            app.refreshWSVarsBtn.Text = 'refresh';
            app.saveWSBtn = uibutton(app.uipanel15, 'push');
            app.saveWSBtn.Text = 'save ws';
            app.deleteSelection = uibutton(app.uipanel15, 'push');
            app.deleteSelection.ButtonPushedFcn = createCallbackFcn(app, @deleteSelection_Callback, true);
            app.deleteSelection.Text = 'delete';
            app.setImageTime = uibutton(app.uipanel15, 'push');
            app.setImageTime.ButtonPushedFcn = createCallbackFcn(app, @setImageTime_Callback, true);
            app.setImageTime.Text = 'image time';
            app.uipanel16 = uipanel(app.figure1);
            app.uipanel16.Title = 'ROI Browser';
            app.roiSelector = uilistbox(app.uipanel16);
            app.roiSelector.Items = {'rois'};
            app.roiSelector.ValueChangedFcn = createCallbackFcn(app, @roiSelector_Callback, true);
            app.roiSelector.Value = 'rois';
            app.holdCurrentROILinesToggle = uicheckbox(app.uipanel16);
            app.holdCurrentROILinesToggle.Text = 'hold current';
            app.uipanel10 = uipanel(app.figure1);
            app.uipanel10.Title = 'Program Nav';
            app.importerBtn = uibutton(app.uipanel10, 'push');
            app.importerBtn.ButtonPushedFcn = createCallbackFcn(app, @importerBtn_Callback, true);
            app.importerBtn.Text = 'Importer';
            app.roiMakerBtn = uibutton(app.uipanel10, 'push');
            app.roiMakerBtn.ButtonPushedFcn = createCallbackFcn(app, @roiMakerBtn_Callback, true);
            app.roiMakerBtn.Text = 'ROI Maker';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Disk Extraction';
            app.firstImageEntry = uieditfield(app.uipanel6, 'text');
            app.firstImageEntry.Value = '1';
            app.endImageEntry = uieditfield(app.uipanel6, 'text');
            app.endImageEntry.Value = '2';
            app.diskRegFlag = uicheckbox(app.uipanel6);
            app.diskRegFlag.Text = 'Register?';
            app.diskExtractButton = uibutton(app.uipanel6, 'push');
            app.diskExtractButton.ButtonPushedFcn = createCallbackFcn(app, @diskExtractButton_Callback, true);
            app.diskExtractButton.Text = 'Disk Extract';
            app.text4 = uilabel(app.uipanel6);
            app.text4.Text = 'to';
            app.diskExtractSkipByToggle = uicheckbox(app.uipanel6);
            app.diskExtractSkipByToggle.Text = 'Skip By?';
            app.diskExtractSkipByEntry = uieditfield(app.uipanel6, 'text');
            app.diskExtractSkipByEntry.Value = '2';
            app.binByToggle = uicheckbox(app.uipanel6);
            app.binByToggle.Text = 'Bin By:';
            app.binByEntry = uieditfield(app.uipanel6, 'text');
            app.binByEntry.Value = '2';
            app.text36 = uilabel(app.uipanel6);
            app.text36.Text = 'Frames:';
            app.uipanel20 = uipanel(app.figure1);
            app.uipanel20.Title = 'Panel';
            app.traceDisplay = uiaxes(app.uipanel20);
            app.traceDisplay.YLim = [0 65535];
            app.csParserPanel = uipanel(app.figure1);
            app.csParserPanel.Title = 'csParser';
            app.loadCSHDF = uibutton(app.csParserPanel, 'push');
            app.loadCSHDF.ButtonPushedFcn = createCallbackFcn(app, @loadCSHDF_Callback, true);
            app.loadCSHDF.Text = 'load HDF';
            app.csListbox = uilistbox(app.csParserPanel);
            app.csListbox.Items = {'csVariables'};
            app.csListbox.Value = 'csVariables';
            app.refreshCSVarBtn = uibutton(app.csParserPanel, 'push');
            app.refreshCSVarBtn.ButtonPushedFcn = createCallbackFcn(app, @refreshCSVarBtn_Callback, true);
            app.refreshCSVarBtn.Text = 'refresh';
            app.plotCSVar = uibutton(app.csParserPanel, 'push');
            app.plotCSVar.ButtonPushedFcn = createCallbackFcn(app, @plotCSVar_Callback, true);
            app.plotCSVar.Text = 'plot sel.';
            app.setCSTime = uibutton(app.csParserPanel, 'push');
            app.setCSTime.Text = 'set csTime';
            app.plotNormToggle = uicheckbox(app.csParserPanel);
            app.plotNormToggle.Text = 'plot normalized';
            app.upSampleTrimImages = uibutton(app.csParserPanel, 'push');
            app.upSampleTrimImages.ButtonPushedFcn = createCallbackFcn(app, @upSampleTrimImages_Callback, true);
            app.upSampleTrimImages.Text = 'us images';
            app.plotScaleEntry = uieditfield(app.csParserPanel, 'text');
            app.plotScaleEntry.Value = '10';
            app.text37 = uilabel(app.csParserPanel);
            app.text37.Text = 'Scale:';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = extractor_App_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @extractor_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end